classdef VectorFields < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Campos_Vectoriales         matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LimpiarButton              matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        CampoTab                   matlab.ui.container.Tab
        EntradaPanel               matlab.ui.container.Panel
        CaractersticasButtonGroup  matlab.ui.container.ButtonGroup
        NingunoButton              matlab.ui.control.RadioButton
        DivergenciaButton          matlab.ui.control.RadioButton
        RotacionalButton           matlab.ui.control.RadioButton
        CampoVectorialFPanel       matlab.ui.container.Panel
        NxyEditField               matlab.ui.control.EditField
        NxyEditFieldLabel          matlab.ui.control.Label
        MxyEditField               matlab.ui.control.EditField
        MxyEditFieldLabel          matlab.ui.control.Label
        CurvaTab                   matlab.ui.container.Tab
        EntradaPanel_2             matlab.ui.container.Panel
        ParmetroButtonGroup        matlab.ui.container.ButtonGroup
        IntervaloSliderT           matlab.ui.control.Slider
        IntervaloSlider_2Label     matlab.ui.control.Label
        tSup                       matlab.ui.control.NumericEditField
        tInf                       matlab.ui.control.NumericEditField
        tLabel                     matlab.ui.control.Label
        CurvaParamtricaCPanel      matlab.ui.container.Panel
        VtEditField                matlab.ui.control.EditField
        VtEditFieldLabel           matlab.ui.control.Label
        UtEditField                matlab.ui.control.EditField
        UtEditFieldLabel           matlab.ui.control.Label
        ElaborErickYairACLabel     matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        CurvaCheckBox              matlab.ui.control.CheckBox
        CampoCheckBox              matlab.ui.control.CheckBox
        GraficarButton             matlab.ui.control.Button
        TabGroup2                  matlab.ui.container.TabGroup
        CampoTab_2                 matlab.ui.container.Tab
        SalidaPanel                matlab.ui.container.Panel
        ControldelGrficodelCampoButtonGroup  matlab.ui.container.ButtonGroup
        ySup                       matlab.ui.control.NumericEditField
        xSup                       matlab.ui.control.NumericEditField
        yInf                       matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        IntervaloSlider            matlab.ui.control.Slider
        IntervaloSliderLabel       matlab.ui.control.Label
        xInf                       matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        PotencialEditField         matlab.ui.control.EditField
        PotencialEditFieldLabel    matlab.ui.control.Label
        DivergenciaEditField       matlab.ui.control.EditField
        DivergenciaEditFieldLabel  matlab.ui.control.Label
        RotacionalEditField        matlab.ui.control.EditField
        RotacionalEditFieldLabel   matlab.ui.control.Label
        CurvaTab_2                 matlab.ui.container.Tab
        SalidaPanel_2              matlab.ui.container.Panel
        CurvaCheckBox_2            matlab.ui.control.CheckBox
        VectoresTangentesCheckBox  matlab.ui.control.CheckBox
        VectoresdelCampoCheckBox   matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)


        function VectorField(app)
            % Función F=M(x,y)i+N(x,y)j
            % Primero se convierte la cadena de texto a variable simbólica
            Mxy=str2sym(app.MxyEditField.Value);
            Nxy=str2sym(app.NxyEditField.Value);
            F=[Mxy,Nxy];
            
            % Valores para llenar el espacio vectorial (multiples usos)
            xrange = app.xInf.Value:app.IntervaloSlider.Value:app.xSup.Value;
            yrange = app.yInf.Value:app.IntervaloSlider.Value:app.ySup.Value;
            [X,Y] = meshgrid(xrange,yrange);
            
            % Hold on
            hold(app.UIAxes, 'on');
            
            % Verificar si el campo vectorial es conservativo encontrando su función
            % potencial
            try
                P = potential(F);
                app.PotencialEditField.Value = string(P);
                z = matlabFunction(P);
                Z = z(X,Y);
                contour(app.UIAxes,X,Y,Z);
            catch
            end
            
            %---------------------
            syms x y z real
            U = [F(1) F(2) 0];
            V = [ x y z ];
            W = [ x y ];
            
            R = curl(U, V);
            rot = R(3);
            app.RotacionalEditField.Value = string(rot);
            div = divergence(F,W);
            app.DivergenciaEditField.Value = string(div);
            
            % Graficar el rotacional o la divergencia
            if app.RotacionalButton.Value == 1
                try
                    
                    ff = inline(vectorize(rot),'x','y');
                    fg = ff(X,Y);
                    A=pcolor(app.UIAxes,X,Y,fg);
                    A.FaceAlpha=0.7;
                    A.FaceColor = 'interp';
                    A.EdgeColor = 'none';
                    axis(app.UIAxes, [app.xInf.Value app.xSup.Value app.yInf.Value app.ySup.Value]);
                    %title(app.UIAxes,'Prueba');
                catch
                end
                
            elseif app.DivergenciaButton.Value == 1
                try
                    
                    ff = inline(vectorize(div),'x','y');
                    fg = ff(X,Y);
                    A=pcolor(app.UIAxes,X,Y,fg);
                    A.FaceAlpha=0.7;
                    A.FaceColor = 'interp';
                    A.EdgeColor = 'none';
                    axis(app.UIAxes, [app.xInf.Value app.xSup.Value app.yInf.Value app.ySup.Value]);
                catch
                end
            end
            
            
            %-p------------------
            
            % vectorf([F1,F2],a:dx:b,c:dy:d)
            % F es una función de dos argumentos aún sin parametrizar
            % xval y yval son los valores que va a tomar para graficar en el intervalo [a:0.x:c]
            
            % Se recive la función, se convierte en vector
            
            F1f = inline(vectorize(app.MxyEditField.Value),'x','y');
            F2f = inline(vectorize(app.NxyEditField.Value),'x','y');
            
            % Calcula el vector para cada punto
            F1g = F1f(X,Y);
            F2g = F2f(X,Y);
            
            % Lo grafica
            quiver(app.UIAxes,X,Y,F1g,F2g,'k', 'Linewidth', 1.5);
            
            % Hold off
            hold(app.UIAxes, 'off');
        end
        
        function CurveVector(app)
            % Función para graficar una curva y el campo sobre esta con magnitud
    
            % Parámetros del gráfico
            hold(app.UIAxes, 'on');
            %ax = gca;
            %app.UIAxes.XLimMode = 'auto';
            %app.UIAxes.YLimMode = 'auto';
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.LineWidth = 2;
            axis(app.UIAxes, 'equal');
            
            % Variables simbólicas
            syms x y
            
            % r(t)
            t=app.tInf.Value:app.IntervaloSliderT.Value:app.tSup.Value;
            tones=ones(size(t));
            %xt=2+2.5.*cos(t);
            %yt=t./2;
            xt=feval(matlabFunction(str2sym(app.UtEditField.Value)),t);
            yt=feval(matlabFunction(str2sym(app.VtEditField.Value)),t);
            
            if app.CurvaCheckBox_2.Value == 1
                % Plot r(t)
                plot(app.UIAxes, xt,yt, 'r','LineWidth',3);
            end
            
            grid(app.UIAxes,"on");
            grid(app.UIAxes,"minor");
            
           
            % T(x,y)
            TMxy = gradient(xt);
            TNxy = gradient(yt);
            if app.VectoresTangentesCheckBox.Value == 1
                % Plot T(x,y)
                qT=quiver(app.UIAxes, xt,yt,TMxy,TNxy);
                qT.Color='r';
                qT.Marker='o';
                qT.LineWidth=2;
                qT.AutoScale= 'off';
            end
            
            
            % F(x,y)
            %Mxy = 0.2.*(tones);   %0.2
            %Nxy = 0.1.*xt;          %0.1*x
            MxyF=matlabFunction(str2sym(app.MxyEditField.Value), 'vars', [x y]);
            [Mxy]=MxyF(xt,yt);
            % Si la función es una constante hay que llenar manualmente Mxy
            if size(Mxy,2) < size(xt,2)
                Mxy = Mxy.*ones(size(xt));
            end
            NxyF=matlabFunction(str2sym(app.NxyEditField.Value), 'vars', [x y]);
            [Nxy]=NxyF(xt,yt);
            % Lo mismo con Nxy, deben tener el mismo # de elementos
            if size(Nxy,2) < size(yt,2)
                Nxy = Nxy.*ones(size(yt));
            end
            %Nxy=feval(matlabFunction(str2sym(app.NxyEditField.Value)),xt, yt);
            if app.VectoresdelCampoCheckBox.Value == 1
                qF=quiver(app.UIAxes, xt,yt,Mxy,Nxy);
                qF.Color='blue';
                qF.Marker='o';
                qF.MarkerSize=3;
                qF.LineStyle='-.';
                qF.LineWidth=2;
                qF.AutoScale= 'on';
            end
            
            % Producto punto
            %R = TMxy.*Mxy;
            %S = TNxy.*Nxy;
            %if app.ProductoPuntoCheckBox.Value == 1
            %    qRS=quiver(app.UIAxes, xt,yt,R, S);
            %    qRS.Color='magenta';
            %    qRS.Marker='o';
            %    qRS.MarkerSize=3;
            %    qRS.LineStyle='-.';
            %    qRS.LineWidth=2;
            %    qRS.AutoScale= 'off';
            %end
            
            hold(app.UIAxes, 'off');
            
        end
    end
            
        methods (Access = public)
        
      
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Campos_Vectoriales.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
        
        cla(app.UIAxes)
        
        if app.CampoCheckBox.Value == 1
            VectorField(app);
        end
        if app.CurvaCheckBox.Value == 1
            CurveVector(app);
        end
        
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
        
            cla(app.UIAxes)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Campos_Vectoriales = uifigure('Visible', 'off');
            app.Campos_Vectoriales.Name = 'Campos Vectoriales (Rotacional y Divergencia)';
            app.Campos_Vectoriales.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Campos_Vectoriales);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ElaborErickYairACLabel = uilabel(app.LeftPanel);
            app.ElaborErickYairACLabel.Tooltip = {'Erick Yair Aguilera Camacho'; 'erickyairac@hotmail.com'};
            app.ElaborErickYairACLabel.Text = {'Elaboró: '; 'Erick Yair A. C.'};
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.CampoTab = uitab(app.TabGroup);
            app.CampoTab.Title = 'Campo';
            app.EntradaPanel = uipanel(app.CampoTab);
            app.EntradaPanel.Title = 'Entrada';
            app.CampoVectorialFPanel = uipanel(app.EntradaPanel);
            app.CampoVectorialFPanel.Title = 'Campo Vectorial (F)';
            app.MxyEditFieldLabel = uilabel(app.CampoVectorialFPanel);
            app.MxyEditFieldLabel.Text = 'M(x,y)';
            app.MxyEditField = uieditfield(app.CampoVectorialFPanel, 'text');
            app.MxyEditField.Tooltip = {'Componente en "i"'};
            app.MxyEditField.Value = ' cos(x+2.*y)';
            app.NxyEditFieldLabel = uilabel(app.CampoVectorialFPanel);
            app.NxyEditFieldLabel.Text = 'N(x,y)';
            app.NxyEditField = uieditfield(app.CampoVectorialFPanel, 'text');
            app.NxyEditField.Tooltip = {'Componente en "j"'};
            app.NxyEditField.Value = 'sin(x-2.*y)';
            app.CaractersticasButtonGroup = uibuttongroup(app.EntradaPanel);
            app.CaractersticasButtonGroup.Title = 'Características';
            app.RotacionalButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.RotacionalButton.Tooltip = {'¿Que tanto gira el flujo?'};
            app.RotacionalButton.Text = 'Rotacional';
            app.RotacionalButton.Value = true;
            app.DivergenciaButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.DivergenciaButton.Tooltip = {'¿Que tanto flujo entra o sale?'};
            app.DivergenciaButton.Text = 'Divergencia';
            app.NingunoButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.NingunoButton.Text = 'Ninguno';
            app.CurvaTab = uitab(app.TabGroup);
            app.CurvaTab.Title = 'Curva';
            app.EntradaPanel_2 = uipanel(app.CurvaTab);
            app.EntradaPanel_2.Title = 'Entrada';
            app.CurvaParamtricaCPanel = uipanel(app.EntradaPanel_2);
            app.CurvaParamtricaCPanel.Title = 'Curva Paramétrica (C)';
            app.UtEditFieldLabel = uilabel(app.CurvaParamtricaCPanel);
            app.UtEditFieldLabel.Text = 'U(t)';
            app.UtEditField = uieditfield(app.CurvaParamtricaCPanel, 'text');
            app.UtEditField.Tooltip = {'Componente en "i"'};
            app.UtEditField.Value = 'cos(t)';
            app.VtEditFieldLabel = uilabel(app.CurvaParamtricaCPanel);
            app.VtEditFieldLabel.Text = 'V(t)';
            app.VtEditField = uieditfield(app.CurvaParamtricaCPanel, 'text');
            app.VtEditField.Tooltip = {'Componente en "j"'};
            app.VtEditField.Value = 'sin(t)';
            app.ParmetroButtonGroup = uibuttongroup(app.EntradaPanel_2);
            app.ParmetroButtonGroup.Title = 'Parámetro';
            app.tLabel = uilabel(app.ParmetroButtonGroup);
            app.tLabel.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.tLabel.Text = 't';
            app.tInf = uieditfield(app.ParmetroButtonGroup, 'numeric');
            app.tInf.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.tSup = uieditfield(app.ParmetroButtonGroup, 'numeric');
            app.tSup.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.tSup.Value = 6.28;
            app.IntervaloSlider_2Label = uilabel(app.ParmetroButtonGroup);
            app.IntervaloSlider_2Label.Text = 'Intervalo';
            app.IntervaloSliderT = uislider(app.ParmetroButtonGroup);
            app.IntervaloSliderT.Limits = [0 1];
            app.IntervaloSliderT.Tooltip = {'Un valor menor grafica más vectores'};
            app.IntervaloSliderT.Value = 0.1;
            app.LimpiarButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Campo Vectorial')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.CampoTab_2 = uitab(app.TabGroup2);
            app.CampoTab_2.Title = 'Campo';
            app.SalidaPanel = uipanel(app.CampoTab_2);
            app.SalidaPanel.Title = 'Salida';
            app.Panel = uipanel(app.SalidaPanel);
            app.Panel.Title = 'Panel';
            app.RotacionalEditFieldLabel = uilabel(app.Panel);
            app.RotacionalEditFieldLabel.Text = 'Rotacional';
            app.RotacionalEditField = uieditfield(app.Panel, 'text');
            app.RotacionalEditField.Tooltip = {'Rojo: Flujo gira en sentido contrario de las manecillas del reloj'; 'Azul: Flujo gira en sentido de las manecillas del reloj'};
            app.DivergenciaEditFieldLabel = uilabel(app.Panel);
            app.DivergenciaEditFieldLabel.Text = 'Divergencia';
            app.DivergenciaEditField = uieditfield(app.Panel, 'text');
            app.DivergenciaEditField.Tooltip = {'Rojo: Es una fuente de flujo'; 'Azul: Es una caída de flujo'};
            app.PotencialEditFieldLabel = uilabel(app.Panel);
            app.PotencialEditFieldLabel.Text = 'Potencial';
            app.PotencialEditField = uieditfield(app.Panel, 'text');
            app.PotencialEditField.Tooltip = {'Si existe'; ' es una función cuyo gradiente resulta en nuestro campo vectorial.'};
            app.ControldelGrficodelCampoButtonGroup = uibuttongroup(app.SalidaPanel);
            app.ControldelGrficodelCampoButtonGroup.Title = 'Control del Gráfico del Campo';
            app.xEditFieldLabel = uilabel(app.ControldelGrficodelCampoButtonGroup);
            app.xEditFieldLabel.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.xEditFieldLabel.Text = 'x';
            app.xInf = uieditfield(app.ControldelGrficodelCampoButtonGroup, 'numeric');
            app.xInf.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.xInf.Value = -1;
            app.IntervaloSliderLabel = uilabel(app.ControldelGrficodelCampoButtonGroup);
            app.IntervaloSliderLabel.Text = 'Intervalo';
            app.IntervaloSlider = uislider(app.ControldelGrficodelCampoButtonGroup);
            app.IntervaloSlider.Limits = [0 1];
            app.IntervaloSlider.Tooltip = {'Un valor menor grafica más vectores'};
            app.IntervaloSlider.Value = 0.1;
            app.yEditFieldLabel = uilabel(app.ControldelGrficodelCampoButtonGroup);
            app.yEditFieldLabel.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "y"'};
            app.yEditFieldLabel.Text = 'y';
            app.yInf = uieditfield(app.ControldelGrficodelCampoButtonGroup, 'numeric');
            app.yInf.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "y"'};
            app.yInf.Value = -1;
            app.xSup = uieditfield(app.ControldelGrficodelCampoButtonGroup, 'numeric');
            app.xSup.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.xSup.Value = 1;
            app.ySup = uieditfield(app.ControldelGrficodelCampoButtonGroup, 'numeric');
            app.ySup.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "y"'};
            app.ySup.Value = 1;
            app.CurvaTab_2 = uitab(app.TabGroup2);
            app.CurvaTab_2.Title = 'Curva';
            app.SalidaPanel_2 = uipanel(app.CurvaTab_2);
            app.SalidaPanel_2.Title = 'Salida';
            app.VectoresdelCampoCheckBox = uicheckbox(app.SalidaPanel_2);
            app.VectoresdelCampoCheckBox.Tooltip = {'Vectores del campo vectorial (F) sobre la curva (C)'};
            app.VectoresdelCampoCheckBox.Text = 'Vectores del Campo';
            app.VectoresdelCampoCheckBox.Value = true;
            app.VectoresTangentesCheckBox = uicheckbox(app.SalidaPanel_2);
            app.VectoresTangentesCheckBox.Tooltip = {'Vectores tangentes a la curva C'};
            app.VectoresTangentesCheckBox.Text = 'Vectores Tangentes';
            app.VectoresTangentesCheckBox.Value = true;
            app.CurvaCheckBox_2 = uicheckbox(app.SalidaPanel_2);
            app.CurvaCheckBox_2.Tooltip = {'Vectores tangentes a la curva C'};
            app.CurvaCheckBox_2.Text = 'Curva';
            app.CurvaCheckBox_2.Value = true;
            app.GraficarButton = uibutton(app.RightPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.CampoCheckBox = uicheckbox(app.RightPanel);
            app.CampoCheckBox.Text = 'Campo';
            app.CampoCheckBox.Value = true;
            app.CurvaCheckBox = uicheckbox(app.RightPanel);
            app.CurvaCheckBox.Text = 'Curva';
            app.CurvaCheckBox.Value = true;
            % Show the figure after all components are created
            app.Campos_Vectoriales.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VectorFields

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Campos_Vectoriales)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Campos_Vectoriales)
        end
    end
end