classdef camposconservativos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Campos_Vectoriales           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ElaborLabel                  matlab.ui.control.Label
        EntradaPanel                 matlab.ui.container.Panel
        CaractersticasButtonGroup    matlab.ui.container.ButtonGroup
        NingunoButton                matlab.ui.control.RadioButton
        DivergenciaButton            matlab.ui.control.RadioButton
        RotacionalButton             matlab.ui.control.RadioButton
        CampoVectorialPanel          matlab.ui.container.Panel
        NxyEditField                 matlab.ui.control.EditField
        NxyEditFieldLabel            matlab.ui.control.Label
        MxyEditField                 matlab.ui.control.EditField
        MxyEditFieldLabel            matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GraficarButton               matlab.ui.control.Button
        SalidaPanel                  matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        PotencialEditField           matlab.ui.control.EditField
        PotencialEditFieldLabel      matlab.ui.control.Label
        DivergenciaEditField         matlab.ui.control.EditField
        DivergenciaEditFieldLabel    matlab.ui.control.Label
        RotacionalEditField          matlab.ui.control.EditField
        RotacionalEditFieldLabel     matlab.ui.control.Label
        ControldelGrficoButtonGroup  matlab.ui.container.ButtonGroup
        ySup                         matlab.ui.control.NumericEditField
        xSup                         matlab.ui.control.NumericEditField
        yInf                         matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        DensidadSlider               matlab.ui.control.Slider
        DensidadSliderLabel          matlab.ui.control.Label
        xInf                         matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        

    end
    
    methods (Access = public)
    
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Campos_Vectoriales.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)

        cla(app.UIAxes)
        
        % Función F=M(x,y)i+N(x,y)j
        % Primero se convierte la cadena de texto a variable simbólica
        Mxy=str2sym(app.MxyEditField.Value);
        Nxy=str2sym(app.NxyEditField.Value);
        F=[Mxy,Nxy]; 
        
        % Valores para llenar el espacio vectorial ()multiples usos
        xrange = app.xInf.Value:app.DensidadSlider.Value:app.xSup.Value;
        yrange = app.yInf.Value:app.DensidadSlider.Value:app.ySup.Value;
        [X,Y] = meshgrid(xrange,yrange);
        
        % Hold on
        hold(app.UIAxes, 'on');
        
        % Verificar si el campo vectorial es conservativo encontrando su función
        % potencial
        try
            P = potential(F);
            app.PotencialEditField.Value = string(P);
            z = matlabFunction(P);
            Z = feval(z,X,Y);
            contour(app.UIAxes,X,Y,Z);
        catch
        end
        
        %---------------------
        syms x y z real
        U = [F(1) F(2) 0];
        V = [ x y z ];
        W = [ x y ];
        
        R = curl(U, V);
        rot = R(3);
        app.RotacionalEditField.Value = string(rot);
        div = divergence(F,W);
        app.DivergenciaEditField.Value = string(div);
        
        % Graficar el rotatorio o la divergencia
        if app.RotacionalButton.Value == 1
            try
                
                ff = inline(vectorize(rot),'x','y');
                fg = ff(X,Y);
                A=pcolor(app.UIAxes,X,Y,fg);
                A.FaceAlpha=0.7;
                A.FaceColor = 'interp';
                A.EdgeColor = 'none';
                axis(app.UIAxes, [app.xInf.Value app.xSup.Value app.yInf.Value app.ySup.Value]);
                %title(app.UIAxes,'Prueba');
            catch
            end
                
        elseif app.DivergenciaButton.Value == 1
            try
                
                ff = inline(vectorize(div),'x','y');
                fg = ff(X,Y);
                A=pcolor(app.UIAxes,X,Y,fg);
                A.FaceAlpha=0.7;
                A.FaceColor = 'interp';
                A.EdgeColor = 'none';
                axis(app.UIAxes, [app.xInf.Value app.xSup.Value app.yInf.Value app.ySup.Value]);
            catch
            end
        end
 
        
        %-p------------------
        
        % vectorf([F1,F2],a:dx:b,c:dy:d)
        % F es una función de dos argumentos aún sin parametrizar
        % xval y yval son los valores que va a tomar para graficar en el intervalo [a:0.x:c]

        % Se recive la función, se convierte en vector
        F1f = inline(vectorize(app.MxyEditField.Value),'x','y');
        F2f = inline(vectorize(app.NxyEditField.Value),'x','y');

        % Calcula el vector para cada punto
        F1g = F1f(X,Y);
        F2g = F2f(X,Y);

        % Lo grafica
        quiver(app.UIAxes,X,Y,F1g,F2g,'k', 'Linewidth', 1.5);

        % Hold off
        hold(app.UIAxes, 'off');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Campos_Vectoriales = uifigure('Visible', 'off');
            app.Campos_Vectoriales.Name = 'Campos Vectoriales (Rotacional y Divergencia)';
            app.Campos_Vectoriales.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Campos_Vectoriales);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EntradaPanel = uipanel(app.LeftPanel);
            app.EntradaPanel.Title = 'Entrada';
            app.CampoVectorialPanel = uipanel(app.EntradaPanel);
            app.CampoVectorialPanel.Title = 'Campo Vectorial';
            app.MxyEditFieldLabel = uilabel(app.CampoVectorialPanel);
            app.MxyEditFieldLabel.Text = 'M(x,y)';
            app.MxyEditField = uieditfield(app.CampoVectorialPanel, 'text');
            app.MxyEditField.Tooltip = {'Componente en "i"'};
            app.MxyEditField.Value = ' cos(x+2.*y)';
            app.NxyEditFieldLabel = uilabel(app.CampoVectorialPanel);
            app.NxyEditFieldLabel.Text = 'N(x,y)';
            app.NxyEditField = uieditfield(app.CampoVectorialPanel, 'text');
            app.NxyEditField.Tooltip = {'Componente en "j"'};
            app.NxyEditField.Value = 'sin(x-2.*y)';
            app.CaractersticasButtonGroup = uibuttongroup(app.EntradaPanel);
            app.CaractersticasButtonGroup.Title = 'Características';
            app.RotacionalButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.RotacionalButton.Tooltip = {'¿Que tanto gira el flujo?'};
            app.RotacionalButton.Text = 'Rotacional';
            app.RotacionalButton.Value = true;
            app.DivergenciaButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.DivergenciaButton.Tooltip = {'¿Que tanto flujo entra o sale?'};
            app.DivergenciaButton.Text = 'Divergencia';
            app.NingunoButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.NingunoButton.Text = 'Ninguno';
            app.ElaborLabel = uilabel(app.LeftPanel);
            app.ElaborLabel.Tooltip = {'Erick Yair Aguilera Camacho'; 'erickyairac@hotmail.com'};
            app.ElaborLabel.Text = 'Elaboró';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Campo Vectorial')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.SalidaPanel = uipanel(app.RightPanel);
            app.SalidaPanel.Title = 'Salida';
            app.ControldelGrficoButtonGroup = uibuttongroup(app.SalidaPanel);
            app.ControldelGrficoButtonGroup.Title = 'Control del Gráfico';
            app.xEditFieldLabel = uilabel(app.ControldelGrficoButtonGroup);
            app.xEditFieldLabel.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.xEditFieldLabel.Text = 'x';
            app.xInf = uieditfield(app.ControldelGrficoButtonGroup, 'numeric');
            app.xInf.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.xInf.Value = -1;
            app.DensidadSliderLabel = uilabel(app.ControldelGrficoButtonGroup);
            app.DensidadSliderLabel.Text = 'Densidad';
            app.DensidadSlider = uislider(app.ControldelGrficoButtonGroup);
            app.DensidadSlider.Limits = [0 1];
            app.DensidadSlider.Tooltip = {'Un valor menor grafica más vectores'};
            app.DensidadSlider.Value = 0.1;
            app.yEditFieldLabel = uilabel(app.ControldelGrficoButtonGroup);
            app.yEditFieldLabel.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "y"'};
            app.yEditFieldLabel.Text = 'y';
            app.yInf = uieditfield(app.ControldelGrficoButtonGroup, 'numeric');
            app.yInf.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "y"'};
            app.yInf.Value = -1;
            app.xSup = uieditfield(app.ControldelGrficoButtonGroup, 'numeric');
            app.xSup.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "x"'};
            app.xSup.Value = 1;
            app.ySup = uieditfield(app.ControldelGrficoButtonGroup, 'numeric');
            app.ySup.Tooltip = {'Límites inferior y superior de nuestra gráfica en el eje "y"'};
            app.ySup.Value = 1;
            app.Panel = uipanel(app.SalidaPanel);
            app.Panel.Title = 'Panel';
            app.RotacionalEditFieldLabel = uilabel(app.Panel);
            app.RotacionalEditFieldLabel.Text = 'Rotacional';
            app.RotacionalEditField = uieditfield(app.Panel, 'text');
            app.RotacionalEditField.Tooltip = {'Rojo: Flujo gira en sentido de las manecillas del reloj'; 'Azul: Flujo gira en sentido contrario a las manecillas del reloj'};
            app.DivergenciaEditFieldLabel = uilabel(app.Panel);
            app.DivergenciaEditFieldLabel.Text = 'Divergencia';
            app.DivergenciaEditField = uieditfield(app.Panel, 'text');
            app.DivergenciaEditField.Tooltip = {'Rojo: Es una fuente de flujo'; 'Azul: Es una caída de flujo'};
            app.PotencialEditFieldLabel = uilabel(app.Panel);
            app.PotencialEditFieldLabel.Text = 'Potencial';
            app.PotencialEditField = uieditfield(app.Panel, 'text');
            app.PotencialEditField.Tooltip = {'La función de origen de nuestra función antes de aplicarle el operador nabla.'};
            app.GraficarButton = uibutton(app.RightPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.Campos_Vectoriales.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camposconservativos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Campos_Vectoriales)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Campos_Vectoriales)
        end
    end
end